classdef AdminPageAddAdmin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image2                matlab.ui.control.Image
        Label                 matlab.ui.control.Label
        PreviousButton        matlab.ui.control.Button
        MailIDEditField       matlab.ui.control.EditField
        MailIDEditFieldLabel  matlab.ui.control.Label
        NameEditField         matlab.ui.control.EditField
        NameEditFieldLabel    matlab.ui.control.Label
        ADDButton             matlab.ui.control.Button
        AddAdminLabel         matlab.ui.control.Label
        Image                 matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ADDButton
        function AddAdminData(app, event)
            err=addAdmin(app.NameEditField.Value,app.MailIDEditField.Value);
            if err==1
                app.Label.Value="Admin with same name exists";
            else
                app.Label.Value="The Admin has been added";
            end
        end

        % Value changing function: NameEditField
        function Removedisplay(app, event)
            app.Label.Value="";
        end

        % Button pushed function: PreviousButton
        function AddAdminPrevious(app, event)
            AdminPage;
            app.UIFigure.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.AddAdminLabel = uilabel(app.UIFigure);
            app.AddAdminLabel.Text = 'Add Admin';
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @AddAdminData, true);
            app.ADDButton.Text = 'ADD';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.ValueChangingFcn = createCallbackFcn(app, @Removedisplay, true);
            app.MailIDEditFieldLabel = uilabel(app.UIFigure);
            app.MailIDEditFieldLabel.Text = 'Mail ID:';
            app.MailIDEditField = uieditfield(app.UIFigure, 'text');
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @AddAdminPrevious, true);
            app.PreviousButton.Text = 'Previous';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdminPageAddAdmin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end