classdef AdminPageInterviewerdetails < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image2                      matlab.ui.control.Image
        PreviousButton              matlab.ui.control.Button
        DepartmentDropDown          matlab.ui.control.DropDown
        DepartmentDropDownLabel     matlab.ui.control.Label
        GetTheMailIDLabel           matlab.ui.control.Label
        GetTheDomainLabel           matlab.ui.control.Label
        GetTheDepartmentLabel       matlab.ui.control.Label
        GetTheStatusLabel           matlab.ui.control.Label
        GetTheNameLabel             matlab.ui.control.Label
        MailIDLabel                 matlab.ui.control.Label
        DomainLabel                 matlab.ui.control.Label
        DepartmentLabel             matlab.ui.control.Label
        StatusLabel                 matlab.ui.control.Label
        NameLabel                   matlab.ui.control.Label
        EntertheNameEditField       matlab.ui.control.EditField
        EntertheNameEditFieldLabel  matlab.ui.control.Label
        SearchButton                matlab.ui.control.Button
        InterviewerDetailsLabel     matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchButton
        function SearchInterviewerDetails(app, event)
            Name=app.EntertheNameEditField.Value;
            Dept=app.DepartmentDropDown.Value;
            if Dept == "Human Resource"
                Dept = "HR";
            elseif Dept == "Technical"
                Dept = "TECH";
            else
                Dept = "MG";
            end
            data = interviewerDetail(Name,Dept);
            app.GetTheNameLabel.Text =data.Name{1};
            app.GetTheDepartmentLabel.Text=data.Department{1};
            app.GetTheDomainLabel.Text=data.Domain{1};
            app.GetTheMailIDLabel.Text=data.Email{1};
            app.GetTheStatusLabel.Text=data.Status{1};
        end

        % Button pushed function: PreviousButton
        function InterviewerDetailsPrevious(app, event)
            AdminPage;
            app.UIFigure.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.InterviewerDetailsLabel = uilabel(app.UIFigure);
            app.InterviewerDetailsLabel.Text = 'Interviewer Details';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchInterviewerDetails, true);
            app.SearchButton.Text = 'Search';
            app.EntertheNameEditFieldLabel = uilabel(app.UIFigure);
            app.EntertheNameEditFieldLabel.Text = 'Enter the Name';
            app.EntertheNameEditField = uieditfield(app.UIFigure, 'text');
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name:';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.DepartmentLabel = uilabel(app.UIFigure);
            app.DepartmentLabel.Text = 'Department:';
            app.DomainLabel = uilabel(app.UIFigure);
            app.DomainLabel.Text = 'Domain:';
            app.MailIDLabel = uilabel(app.UIFigure);
            app.MailIDLabel.Text = 'Mail ID:';
            app.GetTheNameLabel = uilabel(app.UIFigure);
            app.GetTheNameLabel.Text = 'Get The Name';
            app.GetTheStatusLabel = uilabel(app.UIFigure);
            app.GetTheStatusLabel.Text = 'Get The Status';
            app.GetTheDepartmentLabel = uilabel(app.UIFigure);
            app.GetTheDepartmentLabel.Text = 'Get The Department';
            app.GetTheDomainLabel = uilabel(app.UIFigure);
            app.GetTheDomainLabel.Text = 'Get The Domain';
            app.GetTheMailIDLabel = uilabel(app.UIFigure);
            app.GetTheMailIDLabel.Text = 'Get The Mail ID';
            app.DepartmentDropDownLabel = uilabel(app.UIFigure);
            app.DepartmentDropDownLabel.Text = 'Department';
            app.DepartmentDropDown = uidropdown(app.UIFigure);
            app.DepartmentDropDown.Items = {'<Select>', 'Human Resource', 'Technical', 'Manager'};
            app.DepartmentDropDown.Value = '<Select>';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerDetailsPrevious, true);
            app.PreviousButton.Text = 'Previous';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdminPageInterviewerdetails

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end