classdef InterviewerAddReview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StudentIDEditFieldLabel   matlab.ui.control.Label
        StudentIDEditField        matlab.ui.control.EditField
        NOGOButton                matlab.ui.control.Button
        GOButton                  matlab.ui.control.Button
        ReviewEditFieldLabel      matlab.ui.control.Label
        ReviewEditField           matlab.ui.control.EditField
        NameEditField             matlab.ui.control.EditField
        NameEditFieldLabel        matlab.ui.control.Label
        AddReviewForStudentLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        InterviewerName String % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GOButton
        function InterviewerPushGo(app, event)
            addReview(app.StudentIDEditField.Value,app.InterviewerName,app.ReviewEditField.Value,"GO")
        end

        % Button pushed function: NOGOButton
        function InterviewerPushNOGo(app, event)
            addReview(app.StudentIDEditField.Value,app.InterviewerName,app.ReviewEditField.Value,"NOGO")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddReviewForStudentLabel = uilabel(app.UIFigure);
            app.AddReviewForStudentLabel.Text = 'Add Review For Student';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.ReviewEditField = uieditfield(app.UIFigure, 'text');
            app.ReviewEditFieldLabel = uilabel(app.UIFigure);
            app.ReviewEditFieldLabel.Text = 'Review';
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerPushGo, true);
            app.GOButton.Text = 'GO';
            app.NOGOButton = uibutton(app.UIFigure, 'push');
            app.NOGOButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerPushNOGo, true);
            app.NOGOButton.Text = 'NOGO';
            app.StudentIDEditField = uieditfield(app.UIFigure, 'text');
            app.StudentIDEditFieldLabel = uilabel(app.UIFigure);
            app.StudentIDEditFieldLabel.Text = 'Student ID';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterviewerAddReview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end