classdef LoginPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SignUpButton            matlab.ui.control.Button
        PasswordEditField       matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        Label                   matlab.ui.control.Label
        UsernameEditField       matlab.ui.control.EditField
        UsernameEditFieldLabel  matlab.ui.control.Label
        LoginButton             matlab.ui.control.Button
        LoginLabel              matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    
    properties (Access = public)
        Password=""; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SignUpButton
        function SignUpNextPage(app, event)
            LoginPageSignUp
            app.UIFigure.Visible="off";
        end

        % Button pushed function: LoginButton
        function LoginClick(app, event)
           n=login(app.UsernameEditField.Value,app.Password);
           if n==0
               objj=InterviewerPage;
               objj.InterviewerName=app.UsernameEditField.Value;
               objj
               app.UIFigure.Visible="off";
           elseif n==1
               AdminPage;
               app.UIFigure.Visible="off";
           elseif n==-2
               app.Password="";
               app.PasswordEditField.Value="";
               app.Label.FontColor=[1 0 0];
               app.Label.Text="Wrong Password";
           else
               app.Label.FontColor=[1 0 0];
               app.Label.Text = "Please Sign Up";
           end
        end

        % Value changing function: UsernameEditField
        function NameChanged(app, event)
            app.Label.Text="";
        end

        % Value changed function: PasswordEditField
        function EncoderPassword(app, event)
            value = app.PasswordEditField.Value;
            app.Password=value;
            x="";
            for i= 1:length(value)
                x=x+'*';
            end
            app.PasswordEditField.Value=x;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.LoginLabel = uilabel(app.UIFigure);
            app.LoginLabel.Text = 'Login';
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginClick, true);
            app.LoginButton.Text = 'Login';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username:';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.UsernameEditField.ValueChangingFcn = createCallbackFcn(app, @NameChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @EncoderPassword, true);
            app.SignUpButton = uibutton(app.UIFigure, 'push');
            app.SignUpButton.ButtonPushedFcn = createCallbackFcn(app, @SignUpNextPage, true);
            app.SignUpButton.Text = 'Sign Up';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end